classdef login < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LoginPageUIFigure       matlab.ui.Figure
        TryAgainLabel           matlab.ui.control.Label
        PasswordLabel           matlab.ui.control.Label
        LoginIdLabel            matlab.ui.control.Label
        LoginLabel              matlab.ui.control.Label
        Button                  matlab.ui.control.Button
        LoginButton             matlab.ui.control.Button
        SignUpButton            matlab.ui.control.Button
        NewuserLabel            matlab.ui.control.Label
        EnterPasswordEditField  matlab.ui.control.EditField
        EnterLoginIdEditField   matlab.ui.control.EditField
        UITable                 matlab.ui.control.Table
        Image                   matlab.ui.control.Image
    end

    
    properties (Access = private)
        callingapp
       yieldapp
    end  


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, homepg)
             app.callingapp = homepg;
        end

        % Callback function
        function LoginButtonPushed(app, event)
        
        end

        % Callback function
        function EditField_2ValueChanged(app, event)
           global a;
            a = app.EditField_2.Value;            
            
        end

        % Callback function
        function EditField_3ValueChanged(app, event)
            global aa;
            aa = app.EditField_3.Value;
            
        end

        % Button pushed function: SignUpButton
        function SignUpButtonPushed(app, event)
              open(register) ;
            delete(app)
        end

        % Callback function
        function LoginButtonPushed2(app, event)
           
        end

        % Button pushed function: LoginButton
        function LoginButtonPushed3(app, event)
            t= readtable('data.xlsx','sheet',1);
          app.UITable.Data = t;
            n= app.EnterLoginIdEditField.Value;
            b= app.EnterPasswordEditField.Value;
            numRows =size(t,1);
            for i= 1:numRows
                m=strcmp(app.UITable.Data{i,2},n);
                p=strcmp(app.UITable.Data{i,3},b);
                if m==1
                    if p==1
                         %  msgbox('Congratulation! You have logged in');
                          app.EnterLoginIdEditField.Value='';
                          app.EnterPasswordEditField.Value='';
                          app.yieldapp = yield(app);                   
                            delete(app) 
                          % delete(app.EnterLoginIdEditField.Value);
                    else
                        % msgbox('Wrong Password ,Try again');
                        app.TryAgainLabel.Visible = 'on';
                          app.EnterPasswordEditField.Value='';
                    end
                else
                   %  msgbox('Wrong Login Id ,Try again');
                   app.TryAgainLabel.Visible = 'on';
                       app.EnterLoginIdEditField.Value=''; 
                        app.EnterPasswordEditField.Value='';
                end
                
                
            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            
             open(homepg) ;
             delete(app)
                  
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LoginPageUIFigure = uifigure('Visible', 'off');
            app.LoginPageUIFigure.Name = 'Login Page';
            app.Image = uiimage(app.LoginPageUIFigure);
            app.Image.ImageSource = 'p1.jpg';
            app.UITable = uitable(app.LoginPageUIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.UITable.Visible = 'off';
            app.EnterLoginIdEditField = uieditfield(app.LoginPageUIFigure, 'text');
            app.EnterPasswordEditField = uieditfield(app.LoginPageUIFigure, 'text');
            app.NewuserLabel = uilabel(app.LoginPageUIFigure);
            app.NewuserLabel.Text = 'New user ?';
            app.SignUpButton = uibutton(app.LoginPageUIFigure, 'push');
            app.SignUpButton.ButtonPushedFcn = createCallbackFcn(app, @SignUpButtonPushed, true);
            app.SignUpButton.Text = 'Sign Up';
            app.LoginButton = uibutton(app.LoginPageUIFigure, 'push');
            app.LoginButton.ButtonPushedFcn = createCallbackFcn(app, @LoginButtonPushed3, true);
            app.LoginButton.Text = 'Login';
            app.Button = uibutton(app.LoginPageUIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'homeicon.png';
            app.Button.Text = '';
            app.LoginLabel = uilabel(app.LoginPageUIFigure);
            app.LoginLabel.Text = 'Login';
            app.LoginIdLabel = uilabel(app.LoginPageUIFigure);
            app.LoginIdLabel.Text = 'Login Id';
            app.PasswordLabel = uilabel(app.LoginPageUIFigure);
            app.PasswordLabel.Text = 'Password';
            app.TryAgainLabel = uilabel(app.LoginPageUIFigure);
            app.TryAgainLabel.Visible = 'off';
            app.TryAgainLabel.Text = '*Try Again!!';
            % Show the figure after all components are created
            app.LoginPageUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = login(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LoginPageUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LoginPageUIFigure)
        end
    end
end