classdef register < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RegisterPageUIFigure       matlab.ui.Figure
        Button                     matlab.ui.control.Button
        SignInButton               matlab.ui.control.Button
        AlreadyhaveanaccountLabel  matlab.ui.control.Label
        RegisterNowButton          matlab.ui.control.Button
        EditField_3                matlab.ui.control.EditField
        PasswordLabel              matlab.ui.control.Label
        EditField_2                matlab.ui.control.EditField
        EmailIdLabel               matlab.ui.control.Label
        EditField                  matlab.ui.control.EditField
        FullNameLabel              matlab.ui.control.Label
        PleasefillinthisformtocreateanaccountLabel  matlab.ui.control.Label
        RegisterLabel              matlab.ui.control.Label
        Image                      matlab.ui.control.Image
    end

    
    properties (Access = private)
        callingapp1
       
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, homepg)
            app.callingapp1=homepg ;
            
        end

        % Button pushed function: SignInButton
        function SignInButtonPushed(app, event)
           open(login) ;
             delete(app)
        end

        % Button pushed function: RegisterNowButton
        function RegisterNowButtonPushed(app, event)
        
         name = {app.EditField.Value};
         email = {app.EditField_2.Value};
         password = {app.EditField_3.Value};
         data = table(name,email,password);
         writetable(data,'data.xlsx',"WriteMode","append","AutoFitWidth",false)        
          app.EditField.Value='';
          app.EditField_2.Value='';
          app.EditField_3.Value='';
           msgbox('Congratulation! You have registered successfully');
          open(login);
          
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            open(homepg);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RegisterPageUIFigure = uifigure('Visible', 'off');
            app.RegisterPageUIFigure.Name = 'Register Page';
            app.Image = uiimage(app.RegisterPageUIFigure);
            app.Image.ImageSource = '1.jpg';
            app.RegisterLabel = uilabel(app.RegisterPageUIFigure);
            app.RegisterLabel.Text = 'Register';
            app.PleasefillinthisformtocreateanaccountLabel = uilabel(app.RegisterPageUIFigure);
            app.PleasefillinthisformtocreateanaccountLabel.Text = 'Please fill in this form to create an account';
            app.FullNameLabel = uilabel(app.RegisterPageUIFigure);
            app.FullNameLabel.Text = 'Full Name';
            app.EditField = uieditfield(app.RegisterPageUIFigure, 'text');
            app.EmailIdLabel = uilabel(app.RegisterPageUIFigure);
            app.EmailIdLabel.Text = 'Email Id';
            app.EditField_2 = uieditfield(app.RegisterPageUIFigure, 'text');
            app.PasswordLabel = uilabel(app.RegisterPageUIFigure);
            app.PasswordLabel.Text = 'Password';
            app.EditField_3 = uieditfield(app.RegisterPageUIFigure, 'text');
            app.RegisterNowButton = uibutton(app.RegisterPageUIFigure, 'push');
            app.RegisterNowButton.ButtonPushedFcn = createCallbackFcn(app, @RegisterNowButtonPushed, true);
            app.RegisterNowButton.Text = 'Register Now';
            app.AlreadyhaveanaccountLabel = uilabel(app.RegisterPageUIFigure);
            app.AlreadyhaveanaccountLabel.Text = 'Already have an account?';
            app.SignInButton = uibutton(app.RegisterPageUIFigure, 'push');
            app.SignInButton.ButtonPushedFcn = createCallbackFcn(app, @SignInButtonPushed, true);
            app.SignInButton.Text = 'Sign In';
            app.Button = uibutton(app.RegisterPageUIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'homeicon.png';
            app.Button.Text = '';
            % Show the figure after all components are created
            app.RegisterPageUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = register(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RegisterPageUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RegisterPageUIFigure)
        end
    end
end