classdef yield < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        YieldPredictionAppUIFigure     matlab.ui.Figure
        SuccessfullyLoggedInLabel      matlab.ui.control.Label
        Button                         matlab.ui.control.Button
        TotalNumberofFlowersLabel      matlab.ui.control.Label
        GeneratedimagesButton          matlab.ui.control.Button
        BrowseImageButton              matlab.ui.control.Button
        Image2                         matlab.ui.control.Image
        TotalNumberofFlowersEditField  matlab.ui.control.NumericEditField
        DisplayYieldButton             matlab.ui.control.Button
        BlueAxes                       matlab.ui.control.UIAxes
        GreenAxes                      matlab.ui.control.UIAxes
        RedAxes                        matlab.ui.control.UIAxes
        UIAxes2_3                      matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
        ImageAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        callingapp % Description
    end
    
    methods (Access = private)
        
        function updateimage(app,imagefile)
            % For corn.tif, read the second image in the file 
            if strcmp(imagefile,'welcome.JPG')
                im = imread('welcome.JPG', 2);
            else
                try
                    im = imread(imagefile);
                catch ME
                    % If problem reading image, display error message
                    uialert(app.UIFigure, ME.message, 'Image Error');
                    return;
                end            
            end 
            
            % Create histograms based on number of color channels
            switch size(im,3)
                case 1
                    % Display the grayscale image
                    imagesc(app.ImageAxes,im);
                    
                    % Plot all histograms with the same data for grayscale
                    histr = histogram(app.RedAxes, im, 'FaceColor',[1 0 0],'EdgeColor', 'none');
                    histg = histogram(app.GreenAxes, im, 'FaceColor',[0 1 0],'EdgeColor', 'none');
                    histb = histogram(app.BlueAxes, im, 'FaceColor',[0 0 1],'EdgeColor', 'none');
                    
                case 3
                    % Display the truecolor image
                    imagesc(app.ImageAxes,im); % Plot the histograms
                    histr = histogram(app.RedAxes, im(:,:,1), 'FaceColor', [1 0 0], 'EdgeColor', 'none');
                    histg = histogram(app.GreenAxes, im(:,:,2), 'FaceColor', [0 1 0], 'EdgeColor', 'none');
                    histb = histogram(app.BlueAxes, im(:,:,3), 'FaceColor', [0 0 1], 'EdgeColor', 'none');
                    
                otherwise
                    % Error when image is not grayscale or truecolor
                    uialert(app.UIFigure, 'Image must be grayscale or truecolor.', 'Image Error');
                    return;
            end
                % Get largest bin count
                maxr = max(histr.BinCounts);
                maxg = max(histg.BinCounts);
                maxb = max(histb.BinCounts);
                maxcount = max([maxr maxg maxb]);
                
                % Set y axes limits based on largest bin count
                app.RedAxes.YLim = [0 maxcount];
                app.RedAxes.YTick = round([0 maxcount/2 maxcount], 2, 'significant');
                app.GreenAxes.YLim = [0 maxcount];
                app.GreenAxes.YTick = round([0 maxcount/2 maxcount], 2, 'significant');
                app.BlueAxes.YLim = [0 maxcount];
                app.BlueAxes.YTick = round([0 maxcount/2 maxcount], 2, 'significant');
         
        
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, login)
            app.callingapp = login;
            
           % Configure image axes
            app.ImageAxes.Visible = 'off';
            %app.ImageAxes.Colormap = gray(256);
          %  axis(app.ImageAxes, 'image');
            
            % Update the image and histograms
           % updateimage(app, 'welcome.JPG');
        end

        % Button pushed function: DisplayYieldButton
        function DisplayYieldButtonPushed(app, event)
app.SuccessfullyLoggedInLabel.Visible = 'off';
global fname;
a=imread(fname);
%jajua=a;
 %imshow(a),figure
% aasu=a;
baba=rgb2gray(a);
babanu=edge(baba,'canny');
[~,thresha]=otusthresh(baba);

a1=a(:,:,1);
a2=a(:,:,2);
a3=a(:,:,3);
[~,thresh]=otusthresh(a1);
b1=a1>thresh;
close all
[~,thresh]=otusthresh(a2);
b2=a2>thresh;
close all
[~,thresh]=otusthresh(a3);
b3=a3>thresh;
close all
b1=double(b1);
b2=double(b2);
b3=double(b3);

b=cat(3,b1,b2,b3);
%figure,imshow(b)
%figure,imshow(a)
pp=b;
save pp
a=pp;
b=rgb2hsv(a);

aa=rgb2gray(a);
global m;
m=imhist(aa);
%plot(m);
global hue;
hue=b(:,:,1);
%sat=b(:,:,2);
%valu=b(:,:,3);

%imshow(hue);
%prompt = 'What is the inital value Of hue range? ';
%p1=input(prompt);
bbbhue1=hue>=0.16;
%prompt = 'What is the last value of hue range? ';
%p2=input(prompt);
bbbhue2=hue<=0.19;
global foc ;
huefnl=and(bbbhue1,bbbhue2);
 
%imshow(huefnl)
  
 ssd=and(huefnl,babanu);
ffg=not(ssd);
ffff=and(huefnl,ffg);
%imshow(ffff)
huefnl=ffff;
 %imshow(huefnl);
  %t=baba>thresha;
% figure,imshow(t)
%figure,imshow(aasu)

se=strel('disk',3);
fc=imopen(huefnl,se);
foc=imclose(fc,se);
%figure,imshow(foc)

kkk=imfill(foc,'holes');
%figure,imshow(kkk);
[centers, radii] = imfindcircles(kkk,[10 30],'Sensitivity',.83);
%figure, imshow(jajua);
viscircles(centers, radii,'EdgeColor','b');
%Number_of_flowers=length(radii);
app.TotalNumberofFlowersLabel.Visible = 'on';
app.TotalNumberofFlowersEditField.Visible = 'on';
app.TotalNumberofFlowersEditField.Value=length(radii) ;
        end

        % Button pushed function: BrowseImageButton
        function BrowseImageButtonPushed(app, event)
            app.SuccessfullyLoggedInLabel.Visible = 'off';
            
             % Display uigetfile dialog
              app.RedAxes.Visible = 'on';
               app.GreenAxes.Visible = 'on';
                app.BlueAxes.Visible = 'on';
             global fname;
            filterspec = {'*.jpg;*.tif;*.png;*.gif','All Image Files'};
           
           % image1= app.ImageAxes.Value;
            [f, p] = uigetfile(filterspec);
            
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
               fname = [p f];
             
             updateimage(app, fname);
            end
            
        end

        % Button pushed function: GeneratedimagesButton
        function GeneratedimagesButtonPushed(app, event)
           app.SuccessfullyLoggedInLabel.Visible = 'off';
            global hue;
            app.UIAxes2.Visible = 'on';
            imshow(hue,'parent',app.UIAxes2);
            
             global foc;
            app.UIAxes2_3.Visible = 'on';
            imshow(foc,'parent',app.UIAxes2_3);
            
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
             open(homepg);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.YieldPredictionAppUIFigure = uifigure('Visible', 'off');
            app.YieldPredictionAppUIFigure.Name = 'Yield Prediction App ';
            app.ImageAxes = uiaxes(app.YieldPredictionAppUIFigure);
            title(app.ImageAxes, 'Uploaded Image')
            zlabel(app.ImageAxes, 'Z')
            app.ImageAxes.Visible = 'off';
            app.UIAxes2 = uiaxes(app.YieldPredictionAppUIFigure);
            title(app.UIAxes2, 'Grayscale Image')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.Visible = 'off';
            app.UIAxes2_3 = uiaxes(app.YieldPredictionAppUIFigure);
            title(app.UIAxes2_3, 'Hue Extracted Image')
            zlabel(app.UIAxes2_3, 'Z')
            app.UIAxes2_3.Visible = 'off';
            app.RedAxes = uiaxes(app.YieldPredictionAppUIFigure);
            title(app.RedAxes, 'Red')
            xlabel(app.RedAxes, 'Intensity')
            ylabel(app.RedAxes, 'Pixels')
            app.RedAxes.XLim = [0 255];
            app.RedAxes.XTick = [0 128 255];
            app.RedAxes.Visible = 'off';
            app.GreenAxes = uiaxes(app.YieldPredictionAppUIFigure);
            title(app.GreenAxes, 'Green')
            xlabel(app.GreenAxes, 'Intensity')
            ylabel(app.GreenAxes, 'Pixels')
            app.GreenAxes.XLim = [0 255];
            app.GreenAxes.XTick = [0 128 255];
            app.GreenAxes.Visible = 'off';
            app.BlueAxes = uiaxes(app.YieldPredictionAppUIFigure);
            title(app.BlueAxes, 'Blue')
            xlabel(app.BlueAxes, 'Intensity')
            ylabel(app.BlueAxes, 'Pixels')
            app.BlueAxes.XLim = [0 255];
            app.BlueAxes.XTick = [0 128 255];
            app.BlueAxes.Visible = 'off';
            app.DisplayYieldButton = uibutton(app.YieldPredictionAppUIFigure, 'push');
            app.DisplayYieldButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayYieldButtonPushed, true);
            app.DisplayYieldButton.Text = 'Display Yield';
            app.TotalNumberofFlowersEditField = uieditfield(app.YieldPredictionAppUIFigure, 'numeric');
            app.TotalNumberofFlowersEditField.Visible = 'off';
            app.Image2 = uiimage(app.YieldPredictionAppUIFigure);
            app.Image2.ImageSource = 'p1.jpg';
            app.BrowseImageButton = uibutton(app.YieldPredictionAppUIFigure, 'push');
            app.BrowseImageButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseImageButtonPushed, true);
            app.BrowseImageButton.Text = 'Browse Image';
            app.GeneratedimagesButton = uibutton(app.YieldPredictionAppUIFigure, 'push');
            app.GeneratedimagesButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratedimagesButtonPushed, true);
            app.GeneratedimagesButton.Text = 'Generated images';
            app.TotalNumberofFlowersLabel = uilabel(app.YieldPredictionAppUIFigure);
            app.TotalNumberofFlowersLabel.Visible = 'off';
            app.TotalNumberofFlowersLabel.Text = 'Total Number of Flowers :';
            app.Button = uibutton(app.YieldPredictionAppUIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'homeicon.png';
            app.Button.Text = '';
            app.SuccessfullyLoggedInLabel = uilabel(app.YieldPredictionAppUIFigure);
            app.SuccessfullyLoggedInLabel.Text = 'Successfully Logged In!!';
            % Show the figure after all components are created
            app.YieldPredictionAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = yield(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.YieldPredictionAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.YieldPredictionAppUIFigure)
        end
    end
end